classdef Water_Filter_GUI_Group_12 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ChangePipeDirectionButton  matlab.ui.control.Button
        SystemStateLabel           matlab.ui.control.Label
        ErrorTextArea              matlab.ui.control.TextArea
        ErrorTextAreaLabel         matlab.ui.control.Label
        PumpStatusLamp             matlab.ui.control.Lamp
        PumpStatusLabel            matlab.ui.control.Label
        STOPButton                 matlab.ui.control.Button
        STARTButton                matlab.ui.control.Button
        WaterLevelGauge            matlab.ui.control.LinearGauge
        WaterLevelGaugeLabel       matlab.ui.control.Label
        TurbidityLevelGauge        matlab.ui.control.Gauge
        TurbidityLevelGaugeLabel   matlab.ui.control.Label
    end

    
properties (Access = private)
        arduinoObj                 % Arduino connection object
        timer                      % Timer object for real-time updates
    end

    methods (Access = private)

        % Timer callback for real-time updates
function TimerCallback(app)
    try
        % Check if data is available from Arduino
        if app.arduinoObj.NumBytesAvailable > 0
            data = readline(app.arduinoObj); % Read data from Arduino

            % Parse water level data
            if startsWith(data, "Water Level:")
                waterLevel = str2double(extractAfter(data, ":"));
                app.WaterLevelGauge.Value = waterLevel; % Update water level gauge

                % Update system state and pump status
                if waterLevel < 40
                    % Low water level actions
                    app.SystemStateLabel.Text = "System State: Low Water";
                    app.PumpStatusLamp.Color = [1 0 0]; % Red
                    app.ErrorTextArea.Value = "Warning: Low water level!";
                else
                    % Sufficient water level actions
                    app.SystemStateLabel.Text = "System State: Filtering";
                    app.PumpStatusLamp.Color = [0 1 0]; % Green
                    app.ErrorTextArea.Value = ""; % Clear warnings
                end
            elseif startsWith(data, "PUMP:ON")
                % Pump started
                app.PumpStatusLamp.Color = [0 1 0]; % Green
                app.SystemStateLabel.Text = "System State: Pump Running";
            elseif startsWith(data, "PUMP:OFF")
                % Pump stopped
                app.PumpStatusLamp.Color = [1 0 0]; % Red
                app.SystemStateLabel.Text = "System State: Pump Stopped";
            elseif startsWith(data, "PIPE:CHANGED")
                % Pipe direction changed
                app.ErrorTextArea.Value = "Pipe direction changed successfully.";
            elseif startsWith(data, "ERROR:Low Water")
                % Error: Low water level
                app.ErrorTextArea.Value = "Cannot start pump: Water level too low.";
            end
        end
    catch error1
        % Handle errors
        app.ErrorTextArea.Value = "Error: Unable to read data from Arduino.";
        disp(error1.message);
    end
end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
try
        % Check if arduinoObj exists and reconnect if necessary
        if isempty(app.arduinoObj) || ~isvalid(app.arduinoObj)
            app.arduinoObj = serialport("COM4", 38400);
            configureTerminator(app.arduinoObj, "LF");
            flush(app.arduinoObj);
        end

        % Send START command to Arduino
        writeline(app.arduinoObj, "START");

        % Check if the timer exists and restart it
        if isempty(app.timer) || strcmp(app.timer.Running, "off")
            app.timer = timer('ExecutionMode', 'fixedRate', ...
                              'Period', 0.1, ...
                              'TimerFcn', @(~, ~) TimerCallback(app));
            start(app.timer);
        end

        % Update GUI
        app.PumpStatusLamp.Color = [0 1 0]; % Green for active pump
        app.SystemStateLabel.Text = "System State: Filtering";
        app.ErrorTextArea.Value = "Pump started successfully.";
        app.TurbidityLevelGauge.Value = 55;
    catch
        % Display error if unable to start
        app.ErrorTextArea.Value = "Error: Unable to start the pump.";
        app.PumpStatusLamp.Color = [1 0 0]; % Red for error
        app.SystemStateLabel.Text = "System State: Stopped";
    end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
 try
        % Send STOP command to Arduino
        if ~isempty(app.arduinoObj) && isvalid(app.arduinoObj)
            writeline(app.arduinoObj, "STOP");
        end

        % Stop the timer and clear it
        if ~isempty(app.timer) && strcmp(app.timer.Running, "on")
            stop(app.timer);
            delete(app.timer);
            app.timer = [];
        end

        % Update GUI
        app.PumpStatusLamp.Color = [1 0 0]; % Red for stopped pump
        app.SystemStateLabel.Text = "System State: Stopped";
        app.ErrorTextArea.Value = "Pump stopped successfully.";
    catch
        % Handle errors gracefully
        app.ErrorTextArea.Value = "Error: Unable to stop the pump.";
    end
        end

        % Button pushed function: ChangePipeDirectionButton
        function ChangePipeDirectionButtonPushed(app, event)
 try
                writeline(app.arduinoObj, "CHANGE");
                app.ErrorTextArea.Value = "Pipe direction changed successfully.";
                app.TurbidityLevelGauge.Value = 25;
            catch
                app.ErrorTextArea.Value = "Error: Unable to change pipe direction.";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TurbidityLevelGaugeLabel = uilabel(app.UIFigure);
            app.TurbidityLevelGaugeLabel.Text = 'Turbidity Level';
            app.TurbidityLevelGauge = uigauge(app.UIFigure, 'circular');
            app.WaterLevelGaugeLabel = uilabel(app.UIFigure);
            app.WaterLevelGaugeLabel.Text = 'Water Level';
            app.WaterLevelGauge = uigauge(app.UIFigure, 'linear');
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.PumpStatusLabel = uilabel(app.UIFigure);
            app.PumpStatusLabel.Text = 'Pump Status ';
            app.PumpStatusLamp = uilamp(app.UIFigure);
            app.ErrorTextAreaLabel = uilabel(app.UIFigure);
            app.ErrorTextAreaLabel.Text = 'ErrorTextArea';
            app.ErrorTextArea = uitextarea(app.UIFigure);
            app.SystemStateLabel = uilabel(app.UIFigure);
            app.SystemStateLabel.Text = 'SystemStateLabel:';
            app.ChangePipeDirectionButton = uibutton(app.UIFigure, 'push');
            app.ChangePipeDirectionButton.ButtonPushedFcn = createCallbackFcn(app, @ChangePipeDirectionButtonPushed, true);
            app.ChangePipeDirectionButton.Text = 'Change Pipe Direction';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Water_Filter_GUI_Group_12

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end